classdef simpleC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        OutputEditField        matlab.ui.control.NumericEditField
        OutputEditFieldLabel   matlab.ui.control.Label
        Number2EditField       matlab.ui.control.NumericEditField
        Number2EditFieldLabel  matlab.ui.control.Label
        Number1EditField       matlab.ui.control.NumericEditField
        Number1EditFieldLabel  matlab.ui.control.Label
        multiButton            matlab.ui.control.Button
        divButton              matlab.ui.control.Button
        subButton              matlab.ui.control.Button
        addButton              matlab.ui.control.Button
        TextArea               matlab.ui.control.TextArea
        Label_2                matlab.ui.control.Label
        ClearButton            matlab.ui.control.Button
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: not associated with a component
        function OUTPUTPanelSizeChanged(app, event)
            position = app.OUTPUTPanel.Position;
            
        end

        % Value changed function: Number1EditField, Number2EditField
        function Number2EditFieldValueChanged(app, event)

            
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
           app.Number1EditField.Value = 0;
           app.Number2EditField.Value =0;
           app.OutputEditField.Value =0;
        end

        % Button pushed function: addButton
        function addButtonPushed(app, event)
            a = app.Number1EditField.Value;
            b = app.Number2EditField.Value;
            c = a+b;
            app.OutputEditField.Value =c;
        end

        % Button pushed function: subButton
        function subButtonPushed(app, event)
            a =app.Number1EditField.Value;
            b = app.Number2EditField.Value;
            c = a-b;
            app.OutputEditField.Value=c;
        end

        % Button pushed function: divButton
        function divButtonPushed(app, event)
            a =app.Number1EditField.Value ;
            b = app.Number2EditField.Value;
            c = a/b;
            app.OutputEditField.Value = c;
        end

        % Button pushed function: multiButton
        function multiButtonPushed(app, event)
            a = app.Number1EditField.Value;
            b = app.Number2EditField.Value;
            c = a*b;
            app.OutputEditField.Value =c;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.Tag = 'IN2';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Tag = 'CLEAR';
            app.ClearButton.Text = 'Clear';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'SIMPLE CALCULATOR'};
            app.addButton = uibutton(app.UIFigure, 'push');
            app.addButton.ButtonPushedFcn = createCallbackFcn(app, @addButtonPushed, true);
            app.addButton.Text = 'add';
            app.subButton = uibutton(app.UIFigure, 'push');
            app.subButton.ButtonPushedFcn = createCallbackFcn(app, @subButtonPushed, true);
            app.subButton.Text = 'sub';
            app.divButton = uibutton(app.UIFigure, 'push');
            app.divButton.ButtonPushedFcn = createCallbackFcn(app, @divButtonPushed, true);
            app.divButton.Text = 'div';
            app.multiButton = uibutton(app.UIFigure, 'push');
            app.multiButton.ButtonPushedFcn = createCallbackFcn(app, @multiButtonPushed, true);
            app.multiButton.Text = 'multi';
            app.Number1EditFieldLabel = uilabel(app.UIFigure);
            app.Number1EditFieldLabel.Text = 'Number 1';
            app.Number1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Number1EditField.ValueChangedFcn = createCallbackFcn(app, @Number2EditFieldValueChanged, true);
            app.Number2EditFieldLabel = uilabel(app.UIFigure);
            app.Number2EditFieldLabel.Text = 'Number 2';
            app.Number2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Number2EditField.ValueChangedFcn = createCallbackFcn(app, @Number2EditFieldValueChanged, true);
            app.OutputEditFieldLabel = uilabel(app.UIFigure);
            app.OutputEditFieldLabel.Text = 'Output';
            app.OutputEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = simpleC

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end